classdef lab5_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ACPmodeButtonGroup           matlab.ui.container.ButtonGroup
        bitButton_2                  matlab.ui.control.RadioButton
        bitButton                    matlab.ui.control.RadioButton
        WorkingmodeButtonGroup       matlab.ui.container.ButtonGroup
        LowerButton                  matlab.ui.control.RadioButton
        AllButton                    matlab.ui.control.RadioButton
        HigherButton                 matlab.ui.control.RadioButton
        TresholdSpinner              matlab.ui.control.Spinner
        TresholdSpinnerLabel         matlab.ui.control.Label
        NumberofSamplesSpinner       matlab.ui.control.Spinner
        NumberofSamplesSpinnerLabel  matlab.ui.control.Label
        ConnectCOMButton             matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        ButtonStop                   matlab.ui.control.Button
        ButtonStart                  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ComPort % Description
        UARTTimer % Description
        Signal % Description
        Connected
        Treshold
        ACPmode
        NumOfSamp
        WorkMode
        Command
        UpdateTimer
    end
    
    methods (Access = private)
        
        function UARTTimerFunc(app,~,~)
            sread = readline(app.ComPort);

            ydata = app.Signal.YData;
            ydata = circshift(ydata,-1);
            ydata(end) = sread;
            app.Signal.YData = ydata;
                        
        end

        function Update(~,~,~)
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.ComPort = serialport("COM11", 2400);
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 1023];   
            app.Connected = 0;
           
            app.Signal = plot(app.UIAxes,0:100,zeros(1,101));
        
            app.UARTTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.05, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.UARTTimerFunc);      % Specify callback function

            app.UpdateTimer = timer(...
                'ExecutionMode','fixedRate', ...
                'Period', 0.5, ...
                'BusyMode', 'queue', ...
                'TimerFcn', @app.Update);
        end

        % Button pushed function: ButtonStart
        function BtnStartPush(app, event)
            if strcmp(app.UARTTimer.Running, 'off')                
               app.ButtonStart.Enable = "off";
               app.ButtonStop.Enable = "on";
               app.ConnectCOMButton.Enable = "off";
               start(app.UARTTimer);
            end            
        end

        % Button pushed function: ButtonStop
        function BtnStopPush(app, event)
            if strcmp(app.UARTTimer.Running, 'on')
               stop(app.UARTTimer);
               app.ButtonStart.Enable = "on";
               app.ButtonStop.Enable = "off";
               app.ConnectCOMButton.Enable = "on";
            end
        end

        % Button pushed function: ConnectCOMButton
        function ConnectButton(app, event)
            if app.Connected == 0
                app.ComPort = serialport("COM11", 2400);
                app.Lamp.Color = [0 1 0];
                app.ConnectCOMButton.Text = "Disconnect COM";
                app.ButtonStart.Enable = "on";
                app.Connected = 1;                
            else
                delete(app.ComPort);
                app.Lamp.Color = [1 0 0];
                app.ConnectCOMButton.Text = "Connect COM";
                app.ButtonStart.Enable = "off";
                app.Connected = 0;
            end
        end

        % Selection changed function: WorkingmodeButtonGroup
        function WorkingModeChange(app, event)
            selectedButton = app.WorkingmodeButtonGroup.SelectedObject;
            if selectedButton == app.HigherButton
                app.WorkMode = 1;
                app.TresholdSpinner.Enabled = 'on';
            elseif selectedButton == app.AllButton
                app.WorkMode = 2;
                app.TresholdSpinner.Enabled = 'off';
                app.Treshold = 0;
            else
                app.WorkMode = 3; 
                app.TresholdSpinner.Enabled = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ButtonStart = uibutton(app.UIFigure, 'push');
            app.ButtonStart.ButtonPushedFcn = createCallbackFcn(app, @BtnStartPush, true);
            app.ButtonStart.Enable = 'off';
            app.ButtonStart.Text = 'Start';
            app.ButtonStop = uibutton(app.UIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @BtnStopPush, true);
            app.ButtonStop.Enable = 'off';
            app.ButtonStop.Text = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.ConnectCOMButton = uibutton(app.UIFigure, 'push');
            app.ConnectCOMButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton, true);
            app.ConnectCOMButton.Text = 'Connect COM';
            app.NumberofSamplesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofSamplesSpinnerLabel.Text = 'Number of Samples';
            app.NumberofSamplesSpinner = uispinner(app.UIFigure);
            app.NumberofSamplesSpinner.Limits = [1 9999];
            app.NumberofSamplesSpinner.Value = 1;
            app.TresholdSpinnerLabel = uilabel(app.UIFigure);
            app.TresholdSpinnerLabel.Enable = 'off';
            app.TresholdSpinnerLabel.Text = 'Treshold';
            app.TresholdSpinner = uispinner(app.UIFigure);
            app.TresholdSpinner.Limits = [1 1022];
            app.TresholdSpinner.Enable = 'off';
            app.TresholdSpinner.Value = 1;
            app.WorkingmodeButtonGroup = uibuttongroup(app.UIFigure);
            app.WorkingmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WorkingModeChange, true);
            app.WorkingmodeButtonGroup.Title = 'Working mode';
            app.HigherButton = uiradiobutton(app.WorkingmodeButtonGroup);
            app.HigherButton.Text = 'Higher';
            app.AllButton = uiradiobutton(app.WorkingmodeButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.LowerButton = uiradiobutton(app.WorkingmodeButtonGroup);
            app.LowerButton.Text = 'Lower';
            app.ACPmodeButtonGroup = uibuttongroup(app.UIFigure);
            app.ACPmodeButtonGroup.Title = 'ACP mode';
            app.bitButton = uiradiobutton(app.ACPmodeButtonGroup);
            app.bitButton.Text = '8bit';
            app.bitButton.Value = true;
            app.bitButton_2 = uiradiobutton(app.ACPmodeButtonGroup);
            app.bitButton_2.Text = '10bit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end